classdef mortgage_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoanTermYearsEditField        matlab.ui.control.NumericEditField
        LoanTermYearsEditFieldLabel   matlab.ui.control.Label
        MonthlyPaymentEditField       matlab.ui.control.NumericEditField
        MonthlyPaymentEditFieldLabel  matlab.ui.control.Label
        CalculateMortgageButton       matlab.ui.control.Button
        InterestRateEditField         matlab.ui.control.NumericEditField
        InterestRateLabel             matlab.ui.control.Label
        DownPaymentEditField          matlab.ui.control.NumericEditField
        DownPaymentLabel              matlab.ui.control.Label
        PurchaseAmountEditField       matlab.ui.control.NumericEditField
        PurchaseAmountEditFieldLabel  matlab.ui.control.Label
        PrincipalUIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateMortgageButton
        function CalculateMortgageButtonPushed(app, event)
            homePrice = app.PurchaseAmountEditField.Value;
            downPayment = app.DownPaymentEditField.Value;
            loanAmount = homePrice - downPayment;
            interestRate = app.InterestRateEditField.Value;
            
            % Number of years 
            n = app.LoanTermYearsEditField.Value*12;
            r = interestRate/(12*100);
            payment = loanAmount*(r*(1+r)^n)/((1+r)^n - 1);
            payment_months = 1:n;
            annual_interest = zeros(1,n);
            app.MonthlyPaymentEditField.Value = payment;
            principal_amounts = zeros(1,n);
            for ctr = 1:n
                interest = loanAmount*interestRate/100/12;
                annual_interest(ctr) = interest;
                principal = payment - interest;
                principal_amounts(ctr) = principal;
                loanAmount = loanAmount - principal;
            end
            time_years = payment_months/12;
            yvals = ones(1,n)*payment;
            plot(app.PrincipalUIAxes,time_years,annual_interest,...
                time_years,principal_amounts,...
                time_years,yvals,'b--');
            legend(app.PrincipalUIAxes,{'Interest','Principal',...
                'Monthly Payment'},'location','best');
            box(app.PrincipalUIAxes,"on");
            grid(app.PrincipalUIAxes,"on");
            ylim(app.PrincipalUIAxes,[0,payment+50])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrincipalUIAxes = uiaxes(app.UIFigure);
            title(app.PrincipalUIAxes, 'Principal and Interest')
            xlabel(app.PrincipalUIAxes, 'Time (Years)')
            ylabel(app.PrincipalUIAxes, 'Amount')
            zlabel(app.PrincipalUIAxes, 'Z')
            app.PurchaseAmountEditFieldLabel = uilabel(app.UIFigure);
            app.PurchaseAmountEditFieldLabel.Text = 'Purchase Amount';
            app.PurchaseAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PurchaseAmountEditField.Limits = [0 Inf];
            app.PurchaseAmountEditField.ValueDisplayFormat = '%8.f';
            app.PurchaseAmountEditField.Value = 300000;
            app.DownPaymentLabel = uilabel(app.UIFigure);
            app.DownPaymentLabel.Text = '    Down Payment';
            app.DownPaymentEditField = uieditfield(app.UIFigure, 'numeric');
            app.DownPaymentEditField.Limits = [0 Inf];
            app.DownPaymentEditField.ValueDisplayFormat = '%8.f';
            app.InterestRateLabel = uilabel(app.UIFigure);
            app.InterestRateLabel.Text = 'Interest Rate (%) ';
            app.InterestRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterestRateEditField.Limits = [0 100];
            app.InterestRateEditField.RoundFractionalValues = 'on';
            app.InterestRateEditField.Value = 5;
            app.CalculateMortgageButton = uibutton(app.UIFigure, 'push');
            app.CalculateMortgageButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMortgageButtonPushed, true);
            app.CalculateMortgageButton.Text = 'Calculate Mortgage';
            app.MonthlyPaymentEditFieldLabel = uilabel(app.UIFigure);
            app.MonthlyPaymentEditFieldLabel.Text = 'Monthly Payment';
            app.MonthlyPaymentEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonthlyPaymentEditField.Limits = [0 Inf];
            app.LoanTermYearsEditFieldLabel = uilabel(app.UIFigure);
            app.LoanTermYearsEditFieldLabel.Text = 'Loan Term (Years)';
            app.LoanTermYearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoanTermYearsEditField.Limits = [0 50];
            app.LoanTermYearsEditField.RoundFractionalValues = 'on';
            app.LoanTermYearsEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mortgage_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end